classdef FINALproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MilkSwitch                matlab.ui.control.Switch
        MilkSwitchLabel           matlab.ui.control.Label
        PleasewaitforyourcoffeetobepreparedLabel  matlab.ui.control.Label
        ENS101CoffeeMachineLabel  matlab.ui.control.Label
        KM5                       matlab.ui.control.Image
        KM2                       matlab.ui.control.Image
        KM1                       matlab.ui.control.Image
        F50                       matlab.ui.control.Image
        F20                       matlab.ui.control.Image
        F10                       matlab.ui.control.Image
        SUGARSlider               matlab.ui.control.Slider
        SUGARSliderLabel          matlab.ui.control.Label
        TAKECHANGEButton          matlab.ui.control.Button
        Image_6                   matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        Latte100KMLabel_2         matlab.ui.control.Label
        IrishCoffee230KMLabel     matlab.ui.control.Label
        Mocha150KMLabel           matlab.ui.control.Label
        Cappuccino120KMLabel      matlab.ui.control.Label
        Americano090KMLabel       matlab.ui.control.Label
        Espresso070KMLabel        matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        BALANCELabel              matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        KMLabel                   matlab.ui.control.Label
        SELECTCOFFEEDropDown      matlab.ui.control.DropDown
        SELECTCOFFEELabel         matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        Image7                    matlab.ui.control.Image
        Image8                    matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        changeTextDisplayed = false; % Pratiti "Your change is" tekst displej
        coinQuantity_010 = 100; 
        coinQuantity_020 = 100; 
        coinQuantity_050 = 100; 
        coinQuantity_1 = 100;   
        coinQuantity_2 = 100;   
        coinQuantity_5 = 100;   
        CoinDataFilePath = fullfile(fileparts(mfilename('C:\matlab\coinQuantities.mat')), 'coinQuantities.mat');
        coffeeQuantities = [35, 35, 35, 35, 35, 35];
        CoffeeTypes = {'Espresso', 'Americano', 'Cappuccino', 'Mocha', 'Irish coffee', 'Latte'};
        CoffeeDataFilePath = fullfile(fileparts(mfilename('C:\matlab\coffeeQuantities.mat')), 'coffeeQuantities.mat');
    end


    methods (Access = private)

       function saveCoinQuantities(app, ~)
            coinQuantities = [app.coinQuantity_010, app.coinQuantity_020, app.coinQuantity_050, app.coinQuantity_1, app.coinQuantity_2, app.coinQuantity_5];
            save(app.CoinDataFilePath, 'coinQuantities');
        end
        
        function dispenseChange(app, changeAmount)
            % privateBalance varijabla da dobije vrijednosti trenutnog balansa
            privateBalance = str2double(regexp(app.KMLabel.Text, '\d+\.?\d*', 'match', 'once'));
            % Petlja dok se sav kusur ne izbaci
            while changeAmount > 0
                % Provjera za svaku vrstu kovanice
                if changeAmount >= 5 && app.coinQuantity_5 > 0
                    changeAmount = changeAmount - 5;
                    app.coinQuantity_5 = app.coinQuantity_5 - 1;
                    privateBalance = privateBalance - 5;
                elseif changeAmount >= 2 && app.coinQuantity_2 > 0
                    changeAmount = changeAmount - 2;
                    app.coinQuantity_2 = app.coinQuantity_2 - 1;
                    privateBalance = privateBalance - 2;
                elseif changeAmount >= 1 && app.coinQuantity_1 > 0
                    changeAmount = changeAmount - 1;
                    app.coinQuantity_1 = app.coinQuantity_1 - 1;
                    privateBalance = privateBalance - 1;
                elseif changeAmount >= 0.50 && app.coinQuantity_050 > 0
                    changeAmount = changeAmount - 0.50;
                    app.coinQuantity_050 = app.coinQuantity_050 - 1;
                    privateBalance = privateBalance - 0.50;
                elseif changeAmount >= 0.20 && app.coinQuantity_020 > 0
                    changeAmount = changeAmount - 0.20;
                    app.coinQuantity_020 = app.coinQuantity_020 - 1;
                    privateBalance = privateBalance - 0.20;
                elseif changeAmount >= 0.10 && app.coinQuantity_010 > 0
                    changeAmount = changeAmount - 0.10;
                    app.coinQuantity_010 = app.coinQuantity_010 - 1;
                    privateBalance = privateBalance - 0.10;
                else
                    % Ako nema vise, izlaz iz petlje
                    break;
                end

                % Zaokružiti privateBalance
                privateBalance = round(privateBalance, 2);
                changeAmount = round(changeAmount, 2);
            end

            % Provjera da li je sav kusur vracen kako treba
            if changeAmount > 0
                error('Error in dispensing change. Remaining change amount: %.2f', changeAmount);
            end
        end

        % Funkcija za promjenu balansa ovisno od vrijednosti koja je izabrana
        function updateBalance(app, value)
            if nargin < 2
                value = 0; % Početna vrijednost bez unošenja
            end

            % Trenutno stanje balansa
            currentBalanceStr = app.KMLabel.Text;

            try
                % Izbriši 'KM' i promjeni ostatak u broj
                currentBalance = str2double(regexp(currentBalanceStr, '\d+\.?\d*', 'match', 'once'));

                % Da li je broj validan
                if isnan(currentBalance) || isinf(currentBalance)
                    % Ako broj nije validan, balans=0
                    currentBalance = 0;
                end
            catch
                % Ako je error, balans=0
                currentBalance = 0;
            end

            % Updateaj balans sa novom vrijednoscu i dodaj 'KM'
            newBalance = currentBalance + value;
            app.KMLabel.Text = sprintf('%.2f KM', newBalance);        
        end

        function displayChangeText(app)
            % 'Take change' dugme off
            app.TAKECHANGEButton.Visible = 'off';

            % Prikazati 'Take change' dugme zajedno sa 'Your change is'
            changeText = 'Your change is';
            if startsWith(app.Label.Text, changeText)
                app.TAKECHANGEButton.Visible = 'on';
                app.changeTextDisplayed = true;
            else
                app.changeTextDisplayed = false;
            end
        end

        function startCoffeeAnimation(app, coffeeType)
            % Zajednicka lokacija 6 animacija
            basePath = 'C:\matlab\animations\';

            % Svaka animacija za svoj folder
            coffeeImages = containers.Map(...
            {'Espresso', 'Latte', 'Mocha', 'Cappuccino', 'Americano', 'Irish coffee'}, ...
            {fullfile(basePath, 'espresso\'), fullfile(basePath, 'latte\'), ...
            fullfile(basePath, 'mocha\'), fullfile(basePath, 'cappuccino\'), ...
            fullfile(basePath, 'americano\'), fullfile(basePath, 'irishcoffee\')});

            % Ucitava slike iz foldera ovisno od izabrane kafe
            imageDir = coffeeImages(coffeeType);

            % Početak animacije odabrane kafe
            animateImages(app, imageDir);
        end

        function animateImages(app, imageDir)
            % Dobijanje full foldera sa slikama
            imageFiles = dir(fullfile(imageDir)); 
            % Timer za svaku sliku pojedinacno
                for i = 1:length(imageFiles)
                    t = timer;
                    t.StartDelay = 0.5 * (i - 1); 
                    imagePath = fullfile(imageFiles(i).folder, imageFiles(i).name);
                    t.TimerFcn = @(~, ~) displayImageOnUIAxes(app, imagePath);
                    start(t);
                end
            
        end

        function displayImageOnUIAxes(app, imagePath)
        % Prikazivanje slike
        img = imread(imagePath);
        imshow(img, 'Parent', app.UIAxes);
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Lokacija za kolicine kafe
                app.CoffeeDataFilePath = 'C:\matlab\coffeeQuantities.mat';

                % Provjera postoji li
                if exist(app.CoffeeDataFilePath, 'file')
                    % Ucitavanje vrijednosti kolicine kafa
                    loadedData = load(app.CoffeeDataFilePath, 'coffeeQuantities');
                    if isnumeric(loadedData.coffeeQuantities)
                        app.coffeeQuantities = loadedData.coffeeQuantities;
                    else
                        app.coffeeQuantities = [35, 35, 35, 35, 35, 35];
                    end
                else
                    % Ako nema postavljanje vrijednosti
                    app.coffeeQuantities = [35, 35, 35, 35, 35, 35];
                    % Sacuvanje vrijednosti u file
                    coffeeQuantities = app.coffeeQuantities;
                    save(app.CoffeeDataFilePath, 'coffeeQuantities');
                end
            % Path za fajl od para
            filepath = fullfile(app.CoinDataFilePath, 'coinQuantities.mat');
    
            % Provjera postoji li
            if exist(app.CoinDataFilePath, 'file')
                % Ucitavanje para
                loadedData = load(app.CoinDataFilePath, 'coinQuantities');
                app.coinQuantity_010 = loadedData.coinQuantities(1);
                app.coinQuantity_020 = loadedData.coinQuantities(2);
                app.coinQuantity_050 = loadedData.coinQuantities(3);
                app.coinQuantity_1 = loadedData.coinQuantities(4);
                app.coinQuantity_2 = loadedData.coinQuantities(5);
                app.coinQuantity_5 = loadedData.coinQuantities(6);
            else
                % Ako fajl ne postoji, sejvati default u novi file
                app.saveCoinQuantities();
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            selectedCoffee = app.SELECTCOFFEEDropDown.Value;
            coffeeIndex = find(strcmp(app.CoffeeTypes, selectedCoffee));

            % Cijene za svaku vrstu kafe
            coffeePrices = containers.Map(...
                {'Espresso', 'Latte', 'Mocha', 'Cappuccino', 'Americano' 'Irish coffee'}, ...
                [0.70, 1.00, 1.50, 1.20, 0.90, 2.30 ]...
            );

            % Odabrana kafa i cijena
            selectedCoffeePrice = coffeePrices(selectedCoffee);

            % Trenutni balans
            currentBalanceStr = app.KMLabel.Text;
            currentBalance = str2double(regexp(currentBalanceStr, '\d+\.?\d*', 'match', 'once'));

            % Prikazati tekst
            app.PleasewaitforyourcoffeetobepreparedLabel.Visible="on";

            % Izbrisati Milk switch no/yes
            app.MilkSwitch.Visible="off";
            app.MilkSwitchLabel.Visible="off";

            % Provjera ima li dovoljno odabrane kafe u aparatu
                if app.coffeeQuantities(coffeeIndex) > 0
                    
                    % Provjera ima li dovoljno para za kafu
                    if currentBalance >= selectedCoffeePrice
                        % Izbaciti -1 kafe i updateati balance 
                        app.coffeeQuantities(coffeeIndex) = app.coffeeQuantities(coffeeIndex) - 1;
                        updatedBalance = currentBalance - selectedCoffeePrice;
                        app.KMLabel.Text = sprintf('%.2f KM', updatedBalance);

                        % Sacuvati novu verziju kafe u fajl
                        coffeeQuantities = app.coffeeQuantities;
                        save(app.CoffeeDataFilePath, 'coffeeQuantities');

                        % Zapoceti animaciju
                        app.PleasewaitforyourcoffeetobepreparedLabel.Visible = "on";
                        app.MilkSwitch.Visible = "off";
                        app.MilkSwitchLabel.Visible = "off";
                        app.SUGARSliderLabel.Visible = 'off';
                        app.SUGARSlider.Visible = 'off';
                        startCoffeeAnimation(app, selectedCoffee);

                        % Izbrisati tekst 'ENS101 Coffe Machine'
                        app.ENS101CoffeeMachineLabel.Visible="off";

                        % Kusur
                        change = currentBalance - selectedCoffeePrice;
                        app.Label.Text = sprintf('Your change is %.2f KM.', change);
                        displayChangeText(app);
                    else
                        % Ako nema para

                        app.SUGARSliderLabel.Visible = 'off';
                        app.SUGARSlider.Visible = 'off';
                        app.PleasewaitforyourcoffeetobepreparedLabel.Visible="off";
                        app.Label.Text = 'Not enough money.';
                        app.TAKECHANGEButton.Visible = "on";
                    end
                else
                    % Ako nema vise te kafe koja je izabrana
                    app.Label.Text = sprintf('%s is not available.', selectedCoffee);
                end
        
    
        end

        % Button pushed function: TAKECHANGEButton
        function TAKECHANGEButtonPushed(app, event)
           
                change = str2double(regexp(app.Label.Text, '\d+\.?\d*', 'match', 'once'));
                app.saveCoinQuantities(app.CoinDataFilePath);
                app.dispenseChange(change);
                app.saveCoinQuantities(app.CoinDataFilePath);
                % Promjeni balans na 0.00 i prikaži tekst
                app.KMLabel.Text = '0.00 KM';
                app.Label.Text = 'Your change has been returned.';
                app.TAKECHANGEButton.Visible = 'off';
                app.PleasewaitforyourcoffeetobepreparedLabel.Visible="off";
           
        end

        % Image clicked function: F10
        function F10ImageClicked(app, event)
            app.updateBalance(0.10);
            app.coinQuantity_010 = app.coinQuantity_010 + 1; 
            app.saveCoinQuantities(app.CoinDataFilePath);
        end

        % Image clicked function: F20
        function F20ImageClicked(app, event)
            app.updateBalance(0.20);
            app.coinQuantity_020 = app.coinQuantity_020 + 1; 
            app.saveCoinQuantities(app.CoinDataFilePath);
        end

        % Image clicked function: F50
        function F50ImageClicked(app, event)
            app.updateBalance(0.50);
            app.coinQuantity_050 = app.coinQuantity_050 + 1; 
            app.saveCoinQuantities(app.CoinDataFilePath);
        end

        % Image clicked function: KM1
        function KM1ImageClicked(app, event)
            app.updateBalance(1.00);
            app.coinQuantity_1 = app.coinQuantity_1 + 1;
            app.saveCoinQuantities(app.CoinDataFilePath);
        end

        % Image clicked function: KM2
        function KM2ImageClicked(app, event)
            app.updateBalance(2.00);
            app.coinQuantity_2 = app.coinQuantity_2 + 1; 
            app.saveCoinQuantities(app.CoinDataFilePath);
        end

        % Image clicked function: KM5
        function KM5ImageClicked(app, event)
            app.updateBalance(5.00);
            app.coinQuantity_5 = app.coinQuantity_5 + 1; 
            app.saveCoinQuantities(app.CoinDataFilePath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.CLim = [0 255];
            app.UIAxes.ALim = [0 255];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'types', 'coffeokvir.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'types', '123background.png');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SELECTCOFFEELabel = uilabel(app.UIFigure);
            app.SELECTCOFFEELabel.Text = 'SELECT COFFEE:';
            app.SELECTCOFFEEDropDown = uidropdown(app.UIFigure);
            app.SELECTCOFFEEDropDown.Items = {'Espresso', 'Americano', 'Cappuccino', 'Mocha', 'Irish coffee', 'Latte'};
            app.SELECTCOFFEEDropDown.Value = 'Espresso';
            app.KMLabel = uilabel(app.UIFigure);
            app.KMLabel.Text = '0.00 KM';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.BALANCELabel = uilabel(app.UIFigure);
            app.BALANCELabel.Text = {'BALANCE:'; ''; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'types', 'espresso.jpg');
            app.Espresso070KMLabel = uilabel(app.UIFigure);
            app.Espresso070KMLabel.Text = 'Espresso 0.70KM';
            app.Americano090KMLabel = uilabel(app.UIFigure);
            app.Americano090KMLabel.Text = 'Americano 0.90KM';
            app.Cappuccino120KMLabel = uilabel(app.UIFigure);
            app.Cappuccino120KMLabel.Text = 'Cappuccino 1.20KM';
            app.Mocha150KMLabel = uilabel(app.UIFigure);
            app.Mocha150KMLabel.Text = 'Mocha 1.50KM';
            app.IrishCoffee230KMLabel = uilabel(app.UIFigure);
            app.IrishCoffee230KMLabel.Text = 'Irish Coffee 2.30KM';
            app.Latte100KMLabel_2 = uilabel(app.UIFigure);
            app.Latte100KMLabel_2.Text = 'Latte 1.00KM';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'types', 'americano.jpg');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'types', 'cappuccino.jpg');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'types', 'mocha.jpg');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'types', 'latte.jpg');
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'types', 'irish.jpg');
            app.TAKECHANGEButton = uibutton(app.UIFigure, 'push');
            app.TAKECHANGEButton.ButtonPushedFcn = createCallbackFcn(app, @TAKECHANGEButtonPushed, true);
            app.TAKECHANGEButton.Visible = 'off';
            app.TAKECHANGEButton.Text = 'TAKE CHANGE';
            app.SUGARSliderLabel = uilabel(app.UIFigure);
            app.SUGARSliderLabel.Text = 'SUGAR ';
            app.SUGARSlider = uislider(app.UIFigure);
            app.SUGARSlider.Limits = [0 5];
            app.F10 = uiimage(app.UIFigure);
            app.F10.ImageClickedFcn = createCallbackFcn(app, @F10ImageClicked, true);
            app.F10.ImageSource = fullfile(pathToMLAPP, 'types', '10_kf_lice.png');
            app.F20 = uiimage(app.UIFigure);
            app.F20.ImageClickedFcn = createCallbackFcn(app, @F20ImageClicked, true);
            app.F20.ImageSource = fullfile(pathToMLAPP, 'types', '20_kf_lice.png');
            app.F50 = uiimage(app.UIFigure);
            app.F50.ImageClickedFcn = createCallbackFcn(app, @F50ImageClicked, true);
            app.F50.ImageSource = fullfile(pathToMLAPP, 'types', '50_kf_lice.png');
            app.KM1 = uiimage(app.UIFigure);
            app.KM1.ImageClickedFcn = createCallbackFcn(app, @KM1ImageClicked, true);
            app.KM1.ImageSource = fullfile(pathToMLAPP, 'types', '1_km_lice.png');
            app.KM2 = uiimage(app.UIFigure);
            app.KM2.ImageClickedFcn = createCallbackFcn(app, @KM2ImageClicked, true);
            app.KM2.ImageSource = fullfile(pathToMLAPP, 'types', '2_km_lice.png');
            app.KM5 = uiimage(app.UIFigure);
            app.KM5.ImageClickedFcn = createCallbackFcn(app, @KM5ImageClicked, true);
            app.KM5.ImageSource = fullfile(pathToMLAPP, 'types', '5_km_lice.png');
            app.ENS101CoffeeMachineLabel = uilabel(app.UIFigure);
            app.ENS101CoffeeMachineLabel.Text = {'ENS101'; 'Coffee Machine'};
            app.PleasewaitforyourcoffeetobepreparedLabel = uilabel(app.UIFigure);
            app.PleasewaitforyourcoffeetobepreparedLabel.Visible = 'off';
            app.PleasewaitforyourcoffeetobepreparedLabel.Text = 'Please wait for your coffee to be prepared!';
            app.MilkSwitchLabel = uilabel(app.UIFigure);
            app.MilkSwitchLabel.Text = 'Milk';
            app.MilkSwitch = uiswitch(app.UIFigure, 'slider');
            app.MilkSwitch.Items = {'No', 'Yes'};
            app.MilkSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINALproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end